classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        COMPortsListBoxLabel            matlab.ui.control.Label
        COMPortsListBox                 matlab.ui.control.ListBox
        RevolutionsListBoxLabel         matlab.ui.control.Label
        RevolutionsListBox              matlab.ui.control.ListBox
        RefreshButton                   matlab.ui.control.Button
        OpenButton                      matlab.ui.control.Button
        StoppedButton                   matlab.ui.control.StateButton
        DistancetoCenterEditFieldLabel  matlab.ui.control.Label
        DistancetoCenterEditField       matlab.ui.control.NumericEditField
        NumberofRevolutionsEditFieldLabel  matlab.ui.control.Label
        NumberofRevolutionsEditField    matlab.ui.control.NumericEditField
        ExperimetNameEditFieldLabel     matlab.ui.control.Label
        ExperimetNameEditField          matlab.ui.control.EditField
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.COMPortsListBox.Items = serialportlist('available');
            
        end

        % Value changed function: COMPortsListBox
        function COMPortsListBoxValueChanged(app, event)
            value = app.COMPortsListBox.Value;
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.COMPortsListBox.Items = ""
            app.COMPortsListBox.Items = serialportlist('available');
            
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            port = app.COMPortsListBox.Value;
            s = serialport(port,9600);
        end

        % Value changed function: StoppedButton
        function StoppedButtonValueChanged(app, event)
            s = app.StoppedButton.Text;
            if (s == "Stopped")
                app.StoppedButton.Text = "Going"
            elseif (s == "Going")
                app.StoppedButton.Text = "Stopped"
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.COMPortsListBoxLabel = uilabel(app.UIFigure);
            app.COMPortsListBoxLabel.Text = 'COM Ports';
            app.COMPortsListBox = uilistbox(app.UIFigure);
            app.COMPortsListBox.ValueChangedFcn = createCallbackFcn(app, @COMPortsListBoxValueChanged, true);
            app.RevolutionsListBoxLabel = uilabel(app.UIFigure);
            app.RevolutionsListBoxLabel.Text = 'Revolutions';
            app.RevolutionsListBox = uilistbox(app.UIFigure);
            app.RevolutionsListBox.Items = {'1', '2', '3', '4'};
            app.RevolutionsListBox.Value = '1';
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.StoppedButton = uibutton(app.UIFigure, 'state');
            app.StoppedButton.ValueChangedFcn = createCallbackFcn(app, @StoppedButtonValueChanged, true);
            app.StoppedButton.Text = 'Stopped';
            app.DistancetoCenterEditFieldLabel = uilabel(app.UIFigure);
            app.DistancetoCenterEditFieldLabel.Text = 'Distance to Center';
            app.DistancetoCenterEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofRevolutionsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofRevolutionsEditFieldLabel.Text = 'Number of Revolutions';
            app.NumberofRevolutionsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExperimetNameEditFieldLabel = uilabel(app.UIFigure);
            app.ExperimetNameEditFieldLabel.Text = 'Experimet Name';
            app.ExperimetNameEditField = uieditfield(app.UIFigure, 'text');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end