classdef radar_experiment_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        TurntableTab                    matlab.ui.container.Tab
        TurntableCOMPORTDropDownLabel   matlab.ui.control.Label
        TurntableCOMPORTDropDown        matlab.ui.control.DropDown
        RunButton                       matlab.ui.control.Button
        SpeedstepssecEditFieldLabel     matlab.ui.control.Label
        SpeedstepssecEditField          matlab.ui.control.NumericEditField
        RevolutionsEditFieldLabel       matlab.ui.control.Label
        RevolutionsEditField            matlab.ui.control.NumericEditField
        DirectionButtonGroup            matlab.ui.container.ButtonGroup
        ClockwiseButton                 matlab.ui.control.RadioButton
        CounterclockwiseButton          matlab.ui.control.RadioButton
        SaveSettingsCheckBox            matlab.ui.control.CheckBox
        EmergencyStopButton             matlab.ui.control.Button
        PulseTestButton                 matlab.ui.control.Button
        RefreshButton                   matlab.ui.control.Button
        RadarTab                        matlab.ui.container.Tab
        RadarCOMPORTDropDownLabel       matlab.ui.control.Label
        RadarCOMPORTDropDown            matlab.ui.control.DropDown
        FilenameEditFieldLabel          matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        ofFramesEditFieldLabel          matlab.ui.control.Label
        ofFramesEditField               matlab.ui.control.NumericEditField
        EnableTurntableLinkingCheckBox  matlab.ui.control.CheckBox
        EnableDataSaveCheckBox          matlab.ui.control.CheckBox
        EnableDateCheckBox              matlab.ui.control.CheckBox
        FreeRunningButton               matlab.ui.control.Button
        ScanButton                      matlab.ui.control.Button
        EmergencyStopButton_2           matlab.ui.control.Button
        EnableFrameDrawingCheckBox      matlab.ui.control.CheckBox
        UIAxes_2                        matlab.ui.control.UIAxes
        CSARTab                         matlab.ui.container.Tab
        RunBackprojectionButton         matlab.ui.control.Button
        FileNameEditFieldLabel          matlab.ui.control.Label
        FileNameEditField               matlab.ui.control.EditField
        ResolutionnxnEditFieldLabel     matlab.ui.control.Label
        ResolutionnxnEditField          matlab.ui.control.NumericEditField
        RadiusmEditFieldLabel           matlab.ui.control.Label
        RadiusmEditField                matlab.ui.control.NumericEditField
        FractionalViewingRadiusSliderLabel  matlab.ui.control.Label
        FractionalViewingRadiusSlider   matlab.ui.control.Slider
        RotationalOffsetEditFieldLabel  matlab.ui.control.Label
        RotationalOffsetEditField       matlab.ui.control.NumericEditField
        FVRValue                        matlab.ui.control.Label
        UseTestDataCheckBox             matlab.ui.control.CheckBox
        NormalizationButtonGroup        matlab.ui.container.ButtonGroup
        dBButton                        matlab.ui.control.RadioButton
        R2Button                        matlab.ui.control.RadioButton
        NoneButton                      matlab.ui.control.RadioButton
    end

    
    methods (Access = private)
        
        function results = func(app)
            run("Copy_of_CSAR_Simulation.m");
        end
        
        function results = run_csar(app)
            csar_filename = app.FileNameEditField.Value;
            csar_resolution = app.ResolutionnxnEditField.Value;
            csar_offset = app.RotationalOffsetEditField.Value;
            csar_radius = app.RadiusmEditField.Value;
            csar_fractionalRadius = app.FractionalViewingRadiusSlider.Value;
            csar_normalization = app.NormalizationButtonGroup.SelectedObject.Text;
            CSAR_GUI(csar_filename,csar_offset,csar_resolution,csar_resolution,...
                csar_radius,csar_fractionalRadius,csar_normalization)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TurntableCOMPORTDropDown.Items = serialportlist;
        end

        % Callback function
        function RunBackprojectionButtonPushed(app, event)
            func(app)
        end

        % Button pushed function: RunBackprojectionButton
        function RunBackprojectionButtonPushed2(app, event)
            run_csar(app)
        end

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(app, event)
            value = app.FileNameEditField.Value;
        end

        % Value changed function: FractionalViewingRadiusSlider
        function FractionalViewingRadiusSliderValueChanged(app, event)
            value = app.FractionalViewingRadiusSlider.Value;
            app.FVRValue.Text = num2str(value,'%.2f');
        end

        % Value changing function: FractionalViewingRadiusSlider
        function FractionalViewingRadiusSliderValueChanging(app, event)
            changingValue = event.Value;
            app.FVRValue.Text = num2str(changingValue,'%.2f');
        end

        % Value changed function: UseTestDataCheckBox
        function UseTestDataCheckBoxValueChanged(app, event)
            value = app.UseTestDataCheckBox.Value;
        end

        % Selection changed function: NormalizationButtonGroup
        function NormalizationButtonGroupSelectionChanged(app, event)
            selectedButton = app.NormalizationButtonGroup.SelectedObject;
            
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.TurntableCOMPORTDropDown.Items = serialportlist
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TurntableTab = uitab(app.TabGroup);
            app.TurntableTab.Title = 'Turntable';
            app.TurntableCOMPORTDropDownLabel = uilabel(app.TurntableTab);
            app.TurntableCOMPORTDropDownLabel.Text = 'Turntable COM PORT';
            app.TurntableCOMPORTDropDown = uidropdown(app.TurntableTab);
            app.TurntableCOMPORTDropDown.Value = 'Option 2';
            app.RunButton = uibutton(app.TurntableTab, 'push');
            app.RunButton.Text = 'Run';
            app.SpeedstepssecEditFieldLabel = uilabel(app.TurntableTab);
            app.SpeedstepssecEditFieldLabel.Text = 'Speed (steps/sec)';
            app.SpeedstepssecEditField = uieditfield(app.TurntableTab, 'numeric');
            app.RevolutionsEditFieldLabel = uilabel(app.TurntableTab);
            app.RevolutionsEditFieldLabel.Text = 'Revolutions';
            app.RevolutionsEditField = uieditfield(app.TurntableTab, 'numeric');
            app.DirectionButtonGroup = uibuttongroup(app.TurntableTab);
            app.DirectionButtonGroup.Title = 'Direction';
            app.ClockwiseButton = uiradiobutton(app.DirectionButtonGroup);
            app.ClockwiseButton.Text = 'Clockwise';
            app.ClockwiseButton.Value = true;
            app.CounterclockwiseButton = uiradiobutton(app.DirectionButtonGroup);
            app.CounterclockwiseButton.Text = 'Counterclockwise';
            app.SaveSettingsCheckBox = uicheckbox(app.TurntableTab);
            app.SaveSettingsCheckBox.Text = 'Save Settings';
            app.EmergencyStopButton = uibutton(app.TurntableTab, 'push');
            app.EmergencyStopButton.Text = 'Emergency Stop';
            app.PulseTestButton = uibutton(app.TurntableTab, 'push');
            app.PulseTestButton.Text = 'Pulse Test';
            app.RefreshButton = uibutton(app.TurntableTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.RadarTab = uitab(app.TabGroup);
            app.RadarTab.Title = 'Radar';
            app.RadarCOMPORTDropDownLabel = uilabel(app.RadarTab);
            app.RadarCOMPORTDropDownLabel.Text = 'Radar COM PORT';
            app.RadarCOMPORTDropDown = uidropdown(app.RadarTab);
            app.RadarCOMPORTDropDown.Items = {};
            app.RadarCOMPORTDropDown.Value = {};
            app.FilenameEditFieldLabel = uilabel(app.RadarTab);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.RadarTab, 'text');
            app.ofFramesEditFieldLabel = uilabel(app.RadarTab);
            app.ofFramesEditFieldLabel.Text = '# of Frames';
            app.ofFramesEditField = uieditfield(app.RadarTab, 'numeric');
            app.EnableTurntableLinkingCheckBox = uicheckbox(app.RadarTab);
            app.EnableTurntableLinkingCheckBox.Text = 'Enable Turntable Linking';
            app.EnableDataSaveCheckBox = uicheckbox(app.RadarTab);
            app.EnableDataSaveCheckBox.Text = 'Enable Data Save';
            app.EnableDateCheckBox = uicheckbox(app.RadarTab);
            app.EnableDateCheckBox.Text = 'Enable Date';
            app.FreeRunningButton = uibutton(app.RadarTab, 'push');
            app.FreeRunningButton.Text = 'Free Running ';
            app.ScanButton = uibutton(app.RadarTab, 'push');
            app.ScanButton.Text = 'Scan';
            app.EmergencyStopButton_2 = uibutton(app.RadarTab, 'push');
            app.EmergencyStopButton_2.Text = 'Emergency Stop';
            app.EnableFrameDrawingCheckBox = uicheckbox(app.RadarTab);
            app.EnableFrameDrawingCheckBox.Text = 'Enable Frame Drawing';
            app.UIAxes_2 = uiaxes(app.RadarTab);
            title(app.UIAxes_2, 'Radar Waveform View ')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.CSARTab = uitab(app.TabGroup);
            app.CSARTab.Title = 'CSAR';
            app.RunBackprojectionButton = uibutton(app.CSARTab, 'push');
            app.RunBackprojectionButton.ButtonPushedFcn = createCallbackFcn(app, @RunBackprojectionButtonPushed2, true);
            app.RunBackprojectionButton.Text = 'Run Backprojection';
            app.FileNameEditFieldLabel = uilabel(app.CSARTab);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.CSARTab, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameEditFieldValueChanged, true);
            app.FileNameEditField.Value = 'Test';
            app.ResolutionnxnEditFieldLabel = uilabel(app.CSARTab);
            app.ResolutionnxnEditFieldLabel.Text = 'Resolution (nxn)';
            app.ResolutionnxnEditField = uieditfield(app.CSARTab, 'numeric');
            app.ResolutionnxnEditField.Limits = [1 Inf];
            app.ResolutionnxnEditField.Value = 100;
            app.RadiusmEditFieldLabel = uilabel(app.CSARTab);
            app.RadiusmEditFieldLabel.Text = 'Radius (m)';
            app.RadiusmEditField = uieditfield(app.CSARTab, 'numeric');
            app.RadiusmEditField.Value = 1.8;
            app.FractionalViewingRadiusSliderLabel = uilabel(app.CSARTab);
            app.FractionalViewingRadiusSliderLabel.Text = 'Fractional Viewing Radius';
            app.FractionalViewingRadiusSlider = uislider(app.CSARTab);
            app.FractionalViewingRadiusSlider.Limits = [0 1];
            app.FractionalViewingRadiusSlider.ValueChangedFcn = createCallbackFcn(app, @FractionalViewingRadiusSliderValueChanged, true);
            app.FractionalViewingRadiusSlider.ValueChangingFcn = createCallbackFcn(app, @FractionalViewingRadiusSliderValueChanging, true);
            app.FractionalViewingRadiusSlider.Value = 0.25;
            app.RotationalOffsetEditFieldLabel = uilabel(app.CSARTab);
            app.RotationalOffsetEditFieldLabel.Text = 'Rotational Offset';
            app.RotationalOffsetEditField = uieditfield(app.CSARTab, 'numeric');
            app.FVRValue = uilabel(app.CSARTab);
            app.FVRValue.Text = '.25';
            app.UseTestDataCheckBox = uicheckbox(app.CSARTab);
            app.UseTestDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseTestDataCheckBoxValueChanged, true);
            app.UseTestDataCheckBox.Text = 'Use Test Data ';
            app.UseTestDataCheckBox.Value = true;
            app.NormalizationButtonGroup = uibuttongroup(app.CSARTab);
            app.NormalizationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NormalizationButtonGroupSelectionChanged, true);
            app.NormalizationButtonGroup.Title = 'Normalization';
            app.dBButton = uiradiobutton(app.NormalizationButtonGroup);
            app.dBButton.Text = '-3dB';
            app.dBButton.Value = true;
            app.R2Button = uiradiobutton(app.NormalizationButtonGroup);
            app.R2Button.Text = '1/R^2';
            app.NoneButton = uiradiobutton(app.NormalizationButtonGroup);
            app.NoneButton.Text = 'None';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = radar_experiment_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end